classdef TODE_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fig_Main                        matlab.ui.Figure
        menu_file                       matlab.ui.container.Menu
        menu_dlgBox                     matlab.ui.container.Menu
        menu_quit                       matlab.ui.container.Menu
        menu_help                       matlab.ui.container.Menu
        menu_Paper                      matlab.ui.container.Menu
        panel_exposure                  matlab.ui.container.Panel
        ax_overviewImage                matlab.ui.control.Image
        panel_metadata                  matlab.ui.container.Panel
        tbl_metadata                    matlab.ui.control.Table
        panel_ZStack                    matlab.ui.container.Panel
        lbl_depth                       matlab.ui.control.Label
        ax_scalebarZStack               matlab.ui.control.Image
        btn_depth_up                    matlab.ui.control.Button
        btn_depth_down                  matlab.ui.control.Button
        txt_depthvalue                  matlab.ui.control.NumericEditField
        switch_Zoom                     matlab.ui.control.Switch
        slider_depth                    matlab.ui.control.Slider
        ax_stack                        matlab.ui.control.UIAxes
        panel_interpolationProfilePlot  matlab.ui.container.Panel
        group_exportLineData            matlab.ui.container.ButtonGroup
        btn_hidden1                     matlab.ui.control.ToggleButton
        btn_exportLine2                 matlab.ui.control.ToggleButton
        btn_exportLine1                 matlab.ui.control.ToggleButton
        btn_clearInterpolationPlot      matlab.ui.control.StateButton
        btn_drawLine                    matlab.ui.control.StateButton
        ax_linePlot                     matlab.ui.control.UIAxes
        panel_fitDisplay                matlab.ui.container.Panel
        switch_logLin                   matlab.ui.control.Switch
        group_exportDecayData           matlab.ui.container.ButtonGroup
        btn_hidden2                     matlab.ui.control.ToggleButton
        btn_exportPoint2                matlab.ui.control.ToggleButton
        btn_exportPoint1                matlab.ui.control.ToggleButton
        btn_clearFitPlot                matlab.ui.control.StateButton
        btn_selectPoint                 matlab.ui.control.StateButton
        ax_residuals                    matlab.ui.control.UIAxes
        ax_decay                        matlab.ui.control.UIAxes
        panel_dataDisplay               matlab.ui.container.Panel
        silder_brightness               matlab.ui.control.Slider
        lbl_brightness                  matlab.ui.control.Label
        ax_scalebarData                 matlab.ui.control.Image
        btn_exportMAtlab                matlab.ui.control.StateButton
        switch_radSquare                matlab.ui.control.Switch
        btn_clearDataPlot               matlab.ui.control.Button
        group_depth                     matlab.ui.container.ButtonGroup
        btn_findZPlane                  matlab.ui.control.Button
        btn_hiddenDepth                 matlab.ui.control.RadioButton
        btn_depth6                      matlab.ui.control.RadioButton
        btn_depth5                      matlab.ui.control.RadioButton
        btn_depth4                      matlab.ui.control.RadioButton
        btn_depth3                      matlab.ui.control.RadioButton
        btn_depth2                      matlab.ui.control.RadioButton
        btn_depth1                      matlab.ui.control.RadioButton
        group_data                      matlab.ui.container.ButtonGroup
        btn_hiddenData                  matlab.ui.control.ToggleButton
        btn_plotGrid                    matlab.ui.control.ToggleButton
        btn_plotOxygen                  matlab.ui.control.ToggleButton
        btn_plotCounts                  matlab.ui.control.ToggleButton
        ReferenceImageButtonGroup       matlab.ui.container.ButtonGroup
        btn_hiddenReference             matlab.ui.control.ToggleButton
        btn_RefOxyphor2P                matlab.ui.control.ToggleButton
        btn_RefSR101                    matlab.ui.control.ToggleButton
        btn_Vessel                      matlab.ui.control.ToggleButton
        ax_data                         matlab.ui.control.UIAxes
    end

    properties (Access = public)
        root=ctfroot;
        database=struct();
        data=struct();
        numberZPlanes=zeros(1,'uint16');
        imgtype=zeros(1,'uint8');
        artery=zeros(1,'uint8')
        numdepths=zeros(1,'uint8');
        depth_inc=zeros(1,'uint8');
        stack_num=zeros(1,'uint8');
        scat_type=zeros(1,'uint8');
        depth_level=zeros(1,'uint8');
        art_num=zeros(1,'uint8')
        Interplot_counter=zeros(1,'uint8');
        decayplot_counter=zeros(1,'uint8');
        ZStackType=zeros(1,'uint8');
        taus=zeros(2,1,'double');
        stack_path=char([]);
        rad=false;
        image_Axis;
        stack_Axis;
        color;
        sav;       
        line1;
        line2;
        point1;
        point2;
    end
    
    properties (Access = private)
        DialogBoxApp
    end

    methods (Access = public)

        function f_updateArtery(app,artery)
            %
            % After selecting different dataset, run to update UI
            %
            app.artery=artery;
            app.ax_overviewImage.ImageSource=fullfile(app.root,'dbase',num2str(app.artery,'%02d'),'overview.jpg');
            f_updateParameters(app,app.artery,app.depth_level,0)
            if strcmp(app.switch_radSquare.Value,"Radial") && app.rad
                app.data=load(fullfile(app.root,'dbase',num2str(app.artery,'%02d'), 'po2',['pO2-run',num2str(app.depth_level,'%02d'),'-r.mat']));
            else
                app.data=load(fullfile(app.root,'dbase',num2str(app.artery,'%02d'), 'po2',['pO2-run',num2str(app.depth_level,'%02d'),'.mat']));
            end
            cla(app.image_Axis,'reset');
            f_updatePropertyTable(app);           
            f_calculatestack(app,app.slider_depth.Limits(1));
            f_plotimg(app,app.image_Axis,app.data.pO2.references(app.imgtype).image);
            f_plotscat(app,app.image_Axis,app.scat_type);
            app.silder_brightness.Value=1;
        end

        function f_updatePropertyTable(app)  
            %
            % After selecting different dataset, run to update metadata
            % table
            %
            app.tbl_metadata.Data={'Mouse ID',app.data.pO2.dbEntry.mouseID;'Artery ID' ,num2str(app.data.pO2.dbEntry.artery);...
                'Days after Birth',char(between(datetime(app.data.pO2.dbEntry.mouseDOB,'InputFormat','yyyy-MM-dd'),datetime(app.data.pO2.dbEntry.date,'InputFormat','yyyy-MM-dd'),'days'));...
                'Days after Surgery',char(between(datetime(app.data.pO2.dbEntry.mouseSurgery,'InputFormat','yyyy-MM-dd'),datetime(app.data.pO2.dbEntry.date,'InputFormat','yyyy-MM-dd'),'days'));...
                'Sex',app.database.main(app.artery).meta_sex};
            app.tbl_metadata.RowName={};
        end

        function f_updateParameters(app,artery,depth,level)
            %
            % After selecting new inout parameters, update UI
            % Level 0: different dataset
            % Level 1: different depth within dataset
            % Level 2: different Z stack zoom level within dataset 
            %
            if level==0 %new artery
                for iDepth=1:6
                    if ~isnan(app.database.main(artery).data_depth(iDepth))
                        app.(['btn_depth',num2str(iDepth)]).Enable='on';
                        app.(['btn_depth',num2str(iDepth)]).Text=num2str(app.database.main(artery).data_depth(iDepth));
                    else
                        app.(['btn_depth',num2str(iDepth)]).Enable='off';
                        app.(['btn_depth',num2str(iDepth)]).Text='N/A';
                    end
                end
                for iDepth=app.depth_level:-1:1
                    if isnan(app.database.main(artery).data_depth(iDepth))
                        app.depth_level=app.depth_level-1;
                    else
                        app.group_depth.SelectedObject=app.(['btn_depth' num2str(app.depth_level)]);
                        continue;
                    end
                end
                f_updateParameters(app,artery,app.depth_level,1)
                f_updateParameters(app,artery,app.depth_level,2)
            elseif level==1 %new depth
                if app.database.main(app.artery).data_hasSquare(depth)==1 && app.database.main(app.artery).data_hasRad(depth)==1
                    app.switch_radSquare.Enable='on';
                    if strcmp(app.switch_radSquare.Value,"Square")
                        app.switch_radSquare.Value='Square';
                    elseif strcmp(app.switch_radSquare.Value,"Radial")
                        app.switch_radSquare.Value='Radial';
                    end
                    app.rad=true;
                elseif app.database.main(app.artery).data_hasSquare(depth)==1 && app.database.main(app.artery).data_hasRad(depth)==0
                    app.switch_radSquare.Enable='off';
                    app.switch_radSquare.Value='Square';
                    app.rad=false;
                elseif app.database.main(app.artery).data_hasSquare(depth)==0 && app.database.main(app.artery).data_hasRad(depth)==1
                    app.switch_radSquare.Enable='off';
                    app.switch_radSquare.Value='Radial';
                    app.rad=true;
                end
            elseif level==2 %new Z stack zoom level
                if all(app.database.main(artery).Z_stack==0)
                    app.switch_Zoom.Enable=false;
                    app.slider_depth.Enable=false;
                    app.btn_depth_down.Enable=false;
                    app.btn_depth_up.Enable=false;
                    app.ZStackType=0;
                    return;
                elseif any(app.database.main(artery).Z_stack==0)
                    app.switch_Zoom.Enable=true;
                    app.slider_depth.Enable=true;
                    app.btn_depth_down.Enable=true;
                    app.btn_depth_up.Enable=true;
                    app.switch_Zoom.Enable=false;
                else
                    app.switch_Zoom.Enable=true;
                    app.slider_depth.Enable=true;
                    app.btn_depth_down.Enable=true;
                    app.btn_depth_up.Enable=true;
                    app.switch_Zoom.Enable=true;
                end    
                switch app.switch_Zoom.Value
                    case "Zoom out"
                        if app.database.main(artery).Z_stack(1)>0
                            app.ZStackType=1;
                            app.stack_path=fullfile(app.root,'dbase',num2str(app.artery,'%02d'),'stacks','Z1.tif');
                        else
                            app.ZStackType=2;
                            app.stack_path=fullfile(app.root,'dbase',num2str(app.artery,'%02d'),'stacks','Z2.tif');
                            app.switch_Zoom.Value='Zoom in';
                        end
                    case "Zoom in"
                        if app.database.main(artery).Z_stack(2)>0
                            app.ZStackType=2;
                            app.stack_path=fullfile(app.root,'dbase',num2str(app.artery,'%02d'),'stacks','Z2.tif');
                        else
                            app.ZStackType=1;
                            app.stack_path=fullfile(app.root,'dbase',num2str(app.artery,'%02d'),'stacks','Z1.tif');
                            app.switch_Zoom.Value='Zoom out';
                        end
                end
                app.slider_depth.Value=50; %reset to value that is found in ALL datasets
                app.numberZPlanes=app.database.main(artery).Z_stack(app.ZStackType);
                app.depth_inc=app.database.main(artery).Z_inc(app.ZStackType);
                app.numdepths=app.database.main(artery).Z_depth(app.ZStackType);
                tmp.MajorTicks=app.database.main(app.artery).Z_MajTicks(app.ZStackType,:);
                tmp.MajorTickLabels=flip(app.database.main(app.artery).Z_MajLabels(app.ZStackType,:)');
                tmp.Limits=app.database.main(artery).Z_slider(app.ZStackType,:);
                tmp.Value=app.database.main(artery).Z_depth(app.ZStackType);
                tmp.MinorTicks=app.database.main(artery).Z_MinTicks(app.ZStackType,1):app.database.main(artery).Z_MinTicks(app.ZStackType,2):app.database.main(artery).Z_MinTicks(app.ZStackType,3);
                set(app.slider_depth,tmp);
            end
        end

        function f_plotimg(app,~,img)
            %
            % Replot reference image in data figure
            %
            cla(app.ax_data,'reset');
            set(app.ax_data, 'Position',[168,54,410,287],'BackgroundColor',[.8,.8,.8],'title',[],'xlabel',[],'ylabel',[])
            axis(app.ax_data,'square');
            image(app.ax_data,(repmat(img,1,1,3)));
            pbaspect(app.ax_data,[1,1,1])
            hold(app.ax_data,'on');
            plot(app.ax_data,[0,0],[328 500],'w')
            set(app.ax_data.XAxis,'TickLabels',{},'Visible','off')
            set(app.ax_data.YAxis,'TickLabels',{},'Visible','off')
        end

        function f_plotscat(app,ax,type)
            %
            % Replot data overlay in data figure
            %
            switch type
                case "pO2"
                    scatter(ax,app.data.pO2.pointsX,app.data.pO2.pointsY,[],app.data.pO2.pO2Cols,'filled');
                    colormap(ax, jet(256));
                    tmpMinPO2=round(min(double(app.data.pO2.pO2Value)));
                    tmpMaxPO2=round(max(double(app.data.pO2.pO2Value)));
                    c=colorbar(ax);
                    clim(ax,[tmpMinPO2,tmpMaxPO2]);
                    set(c.Label,'String','pO_2 (mmHg)','FontSize',12,'FontName','Helvetica');
                    pos=get(c.Label,'Position');
                    set(c.Label,'Position',[pos(1)+1 pos(2)],'Rotation',270);
                    set(ax, 'Position', [168,54,410,287]);
                case "photon"
                    scatter(ax,app.data.pO2.pointsX,app.data.pO2.pointsY,[],app.data.pO2.photonCountCols,'filled');
                    colormap(ax, jet(256));
                    clim(ax,[min(app.data.pO2.sum),max(app.data.pO2.sum)])
                    c=colorbar(ax);
                    set(c.Label,'String','Photon Count','FontSize',12,'FontName','Helvetica');
                    c.Ruler.Exponent = 3;
                    pos=get(c.Label,'Position');
                    set(c.Label,'Position',[pos(1)+1 pos(2)],'Rotation',270);
                    set(ax, 'Position', [168,54,410,287])
            end
            drawnow;
        end

        function f_calculatestack(app,depth)
            %
            % Calculate and load Z plane for the Z stack figure
            %
            warning('off') % supresses warnings from Tiff library
            depth=int16(depth);
            if app.switch_Zoom.Value=="Zoom out"
                X=idivide(depth,app.depth_inc,'round');
                selectedPlane=double(X+1)+double(idivide(int16(app.database.main(app.artery).Z_offset(1)),app.depth_inc,"round"));
            else
                X=idivide(depth,app.depth_inc,'round');
                selectedPlane=double(X+1)+double(idivide(int16(app.database.main(app.artery).Z_offset(2)),app.depth_inc,"round"));
            end
            hold=double(X*app.depth_inc);
            app.txt_depthvalue.Value=hold;
            tiffObj=Tiff(app.stack_path,'r');
            if selectedPlane>app.numberZPlanes
                setDirectory(tiffObj,app.numberZPlanes);
            elseif selectedPlane<1
                setDirectory(tiffObj,1);
            else
                setDirectory(tiffObj,selectedPlane);
            end
            img=read(tiffObj);
            f_plotstack(app,app.stack_Axis,img)
            close(tiffObj);
            warning('on')
        end

        function f_plotstack(app,ax,img)
            %
            % Plot Z image in the Z stack figure
            %
            cla(ax,'reset');
            colorbar(ax,'delete');
            set(ax,'title',[],'xlabel',[],'ylabel',[])
            app.ax_stack.Visible='off';
            I=imagesc(ax,img,[0 4095]);
            colormap(ax,'gray')
            set(ax,'XLim',[0 I.XData(2)],'YLim',[0 I.YData(2)],'BackgroundColor',[.8,.8,.8])
            set(ax.XAxis,'Visible','off','TickLabels',{})
            set(ax.YAxis,'Visible','off','TickLabels',{})
        end

        function [xi] = f_pointselect(app,ax)
            %
            % Select point in data figure
            %
            app.decayplot_counter=app.decayplot_counter+1;
            delete(findall(ax, 'Type',  'images.roi.Rectangle'));
            pan(ax, 'off');
            roi = drawcrosshair(app.image_Axis,'Color','White','Visible','on','DrawingArea',[min(app.data.pO2.pointsX),min(app.data.pO2.pointsY),max(app.data.pO2.pointsX)-min(app.data.pO2.pointsX),max(app.data.pO2.pointsY)-min(app.data.pO2.pointsY)]);
            [~,ix]=min(pdist2([app.data.pO2.pointsX' app.data.pO2.pointsY'],roi.Position));
            delete(roi);
            xi=ix;
        end

        function [x,y,d] = f_selectDatapoints(app,ax)
            %
            % Create line plot
            %
            delete(findall(ax, 'Type',  'images.roi.Rectangle'))
            colors=['w','m'];
            app.Interplot_counter=app.Interplot_counter+1;
            pan(ax, 'off')
            roi = drawline(app.image_Axis,'Color',colors(app.Interplot_counter),'InteractionsAllowed','none','DrawingArea',[min(app.data.pO2.pointsX),min(app.data.pO2.pointsY),max(app.data.pO2.pointsX)-min(app.data.pO2.pointsX),max(app.data.pO2.pointsY)-min(app.data.pO2.pointsY)]);
            ep = roi.Position;
            x1 = ep(1,1); y1 = ep(1,2); x2 = ep(2,1); y2 = ep(2,2);
            xy=[linspace(x1,x2).' linspace(y1,y2).'];
            xchange=abs(x1-x2)*app.data.pO2.pixelSizeX;
            ychange=abs(y1-y2)*app.data.pO2.pixelSizeY;
            d=sqrt(xchange^2+ychange^2);
            if ~isvalid(roi)
                x = [];
                y = [];
                return
            end
            x = xy(:,1);
            y = xy(:,2);
        end

        function [executableFolder] = GetExecutableFolder(app)
            %
            % Ensure that parts of the app are locatable during execution
            %
            if isdeployed
                [~, result] = system('set PATH');
                executableFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else
                executableFolder = fileparts(which('TODE_v1'));
                addpath([executableFolder ,filesep,'dbase']);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %warning('off','all')
            app.fig_Main.Visible='off';
            app.root=GetExecutableFolder(app);
            app.database=load(fullfile(app.root,'dbase','database.mat'),'main');
            app.artery=1;
            app.depth_level=1;
            app.btn_hiddenData.Value=true;
            app.data=load(fullfile(app.root,'dbase',num2str(app.artery,'%02d'), 'po2','pO2-run01.mat'));
            f_updateParameters(app,app.artery,app.depth_level,0)
            app.image_Axis=app.ax_data;
            app.stack_Axis=app.ax_stack;
            app.stack_path=fullfile(app.root,'dbase',num2str(app.artery,'%02d'),'stacks','Z1.tif');
            app.ax_scalebarData.ImageSource=fullfile(app.root,'dbase','scalebar.png');           
            f_calculatestack(app,app.slider_depth.Limits(1));
            %%plot data iamge           
            app.scat_type="none";
            app.imgtype=2;
            app.btn_Vessel.Value = true;
            app.silder_brightness.Value=1;
            app.stack_Axis.Color=[.65,.65,.65];
            f_plotimg(app,app.ax_data,app.data.pO2.references(app.imgtype).image);
            app.ax_overviewImage.ImageSource=fullfile(app.root,'dbase',num2str(app.artery,'%02d'),'overview.jpg');
            app.ax_scalebarZStack.ImageSource=fullfile(app.root,'dbase','scalebar_stack_zoomout.png');

            %%update table
            f_updatePropertyTable(app)

            app.btn_hidden1.Value=true;
            app.btn_exportLine1.Enable='off';
            app.btn_exportLine2.Enable='off';

            app.btn_hidden2.Value=true;
            app.btn_exportPoint1.Enable='off';
            app.btn_exportPoint2.Enable='off';

            app.fig_Main.Visible='on';
            app.DialogBoxApp=dlgBox(app);
        end

        % Callback function
        function PlotpO2ButtonPushed(app, event)
            f_plotscat(app,app.image_Axis,"pO2");
            app.scat_type="pO2";
        end

        % Callback function
        function PlotphotoncountButtonPushed(app, event)
            f_plotscat(app,app.image_Axis,"photon");
            app.scat_type="photon";
        end

        % Selection changed function: ReferenceImageButtonGroup
        function ReferenceImageButtonGroupSelectionChanged(app, event)
            selectedButton = app.ReferenceImageButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "FITC-Dextran"
                app.imgtype=2;
                case "SR101"
                app.imgtype=1;
                case "Oxyphor 2p"
                app.imgtype=3;
            end
            app.silder_brightness.Value=1;
            f_plotimg(app,app.image_Axis,app.data.pO2.references(app.imgtype).image)
            if ~strcmp(app.scat_type,"none")
                f_plotscat(app,app.image_Axis,app.scat_type);
            end
        end

        % Button pushed function: btn_clearDataPlot
        function btn_clearDataPlotPushed(app, event)
            f_plotimg(app,app.image_Axis,app.data.pO2.references(app.imgtype).image)
            app.btn_hiddenData.Value=true;
            app.scat_type="none";
        end

        % Selection changed function: group_depth
        function group_depthSelectionChanged(app, event)
            selectedButton = app.group_depth.SelectedObject;
            switch selectedButton
                case app.btn_depth1
                    app.depth_level=1;
                case app.btn_depth2
                    app.depth_level=2;
                case app.btn_depth3
                    app.depth_level=3;
                case app.btn_depth4
                    app.depth_level=4;
                case app.btn_depth5
                    app.depth_level=5;
                case app.btn_depth6
                    app.depth_level=6;
            end
            
            f_updateParameters(app,app.artery,app.depth_level,1)

            if app.rad==false
                app.data=load(fullfile(app.root,'dbase',num2str(app.artery,'%02d'), 'po2',['pO2-run',num2str(app.depth_level,'%02d'),'.mat']));
            elseif app.rad==true && strcmp(app.switch_radSquare.Value,"Radial")
                app.data=load(fullfile(app.root,'dbase',num2str(app.artery,'%02d'), 'po2',['pO2-run',num2str(app.depth_level,'%02d'),'-r.mat']));
            elseif app.rad==true && strcmp(app.switch_radSquare.Value,"Square")
                app.data=load(fullfile(app.root,'dbase',num2str(app.artery,'%02d'), 'po2',['pO2-run',num2str(app.depth_level,'%02d'),'.mat']));
            end
            
            if app.imgtype~=0
                f_plotimg(app,app.image_Axis,app.data.pO2.references(app.imgtype).image);
            end
            if app.scat_type~="none"
                cla(app.image_Axis,'reset');
                f_plotimg(app,app.image_Axis,app.data.pO2.references(app.imgtype).image);
                f_plotscat(app,app.image_Axis,app.scat_type);
            end

            app.silder_brightness.Value=1;
            app.image_Axis.YAxis.Visible='off';
            app.image_Axis.XAxis.Visible='off';

        end

        % Selection changed function: group_data
        function group_dataSelectionChanged(app, event)
            selectedButton = app.group_data.SelectedObject;
            if selectedButton==app.btn_plotCounts
                cla(app.image_Axis,'reset');
                f_plotimg(app,app.image_Axis,app.data.pO2.references(app.imgtype).image);
                f_plotscat(app,app.image_Axis,"photon");
                app.scat_type="photon";
            elseif selectedButton==app.btn_plotOxygen
                cla(app.image_Axis,'reset');
                f_plotimg(app,app.image_Axis,app.data.pO2.references(app.imgtype).image);
                f_plotscat(app,app.image_Axis,"pO2");
                app.scat_type="pO2";
            elseif selectedButton==app.btn_plotGrid
                cla(app.image_Axis,'reset');
                f_plotimg(app,app.image_Axis,app.data.pO2.references(app.imgtype).image);
                scatter(app.image_Axis,app.data.pO2.pointsX,app.data.pO2.pointsY,10,'y','filled');
                app.scat_type="grid";
            end
        end

        % Value changed function: btn_drawLine
        function btn_drawLineValueChanged(app, event)
            %value = app.btn_drawLine.Value;
            app.btn_drawLine.Value=0;

            %make sure there are no more then 2 lines
            if app.Interplot_counter==2
                return
            end

            [x,y,d]=f_selectDatapoints(app,app.image_Axis);
            F=scatteredInterpolant(app.data.pO2.pointsX',app.data.pO2.pointsY',app.data.pO2.pO2Value);
            p=F(x,y);
            distance=linspace(0,d);

            if app.Interplot_counter==1
                app.btn_exportLine1.Enable="on";
                app.line1=[distance' p];
            end

            if app.Interplot_counter==2
                app.btn_exportLine2.Enable="on";
                app.line2=[distance' p];
            end

            plot(app.ax_linePlot,distance,p,"LineWidth",1.5);
            hold(app.ax_linePlot,'on');
            legend(app.ax_linePlot,{'line1','line2','line3','line4'},'TextColor','white');
        end

        % Value changed function: btn_clearInterpolationPlot
        function btn_clearInterpolationPlotValueChanged(app, event)
            app.btn_clearInterpolationPlot.Value=0;
            app.Interplot_counter=0;
            g=get(app.ax_data,'children');
            t = findobj(g, 'Type', 'images.roi.Line'); delete(t);
            cla(app.ax_linePlot);
            app.btn_hidden1.Value=true;
            app.btn_exportLine1.Enable='off';
            app.btn_exportLine2.Enable='off';

        end

        % Callback function
        function ArterySelectionsButtonGroupSelectionChanged(app, event)

        end

        % Value changed function: btn_selectPoint
        function btn_selectPointValueChanged(app, event)
            app.btn_selectPoint.Value=0;
            colors=['w','m'];
            if app.decayplot_counter==2
                return
            end
            xi=f_pointselect(app,app.image_Axis);
            if app.decayplot_counter==1
                app.taus(1)=app.data.pO2.fitC2(xi);
                app.btn_exportPoint1.Enable="on";
                app.point1=app.data.pO2.data(:,xi);
            elseif app.decayplot_counter==2
                app.taus(2)=app.data.pO2.fitC2(xi);
                app.btn_exportPoint2.Enable="on";
                app.point2=app.data.pO2.data(:,xi);
            end
            viscircles(app.image_Axis,[app.data.pO2.pointsX(xi),app.data.pO2.pointsY(xi)],5,'Color',colors(app.decayplot_counter));
            close(gcf)
            hold(app.ax_decay,'on')
            scatter(app.ax_decay,app.data.pO2.tNew,app.data.pO2.data(:,xi),9,'Color',colors(app.decayplot_counter));
            plot(app.ax_decay,app.data.pO2.fittedCurve(xi,:),'LineWidth',1.5,'Color',colors(app.decayplot_counter))

            if app.decayplot_counter==1
                tau1=sprintf('\\tau = %.2f μs',app.taus(1));
                legend(app.ax_decay,{'',tau1},'TextColor','white');
            else
                tau1=sprintf('\\tau = %.2f μs',app.taus(1));
                tau2=sprintf('\\tau = %.2f μs',app.taus(2)');
                legend(app.ax_decay,{'',tau1,'',tau2},'TextColor','white');
            end
            hold(app.ax_residuals,'on')
            scatter(app.ax_residuals,app.data.pO2.tNew,app.data.pO2.data(:,xi)-transpose(app.data.pO2.fittedCurve(xi,:)),9,'Color',colors(app.decayplot_counter)');
            yticklabels(app.ax_residuals,'auto')
            yticks(app.ax_residuals,'auto')
            axis(app.ax_decay,[0 app.data.pO2.tNew(end) -Inf +Inf]);
            axis(app.ax_residuals,[0 app.data.pO2.tNew(end) -70 70]);
        end

        % Value changed function: switch_Zoom
        function switch_ZoomValueChanged(app, event)
            switch app.switch_Zoom.Value
                case "Zoom in"
                app.stack_path=fullfile(app.root,'dbase',num2str(app.artery,'%02d'),'stacks','Z2.tif');
                app.ax_scalebarZStack.ImageSource=fullfile(app.root,'dbase','scalebar_stack_zoomin.png');
                case "Zoom out"
                app.stack_path=fullfile(app.root,'dbase',num2str(app.artery,'%02d'),'stacks','Z1.tif');
                app.ax_scalebarZStack.ImageSource=fullfile(app.root,'dbase','scalebar_stack_zoomout.png');
            end
            f_updateParameters(app,app.artery,app.depth_level,2)
            app.slider_depth.Value=app.numdepths-app.txt_depthvalue.Value;
            f_calculatestack(app,app.txt_depthvalue.Value)
        end

        % Value changed function: switch_radSquare
        function switch_radSquareValueChanged(app, event)
            switch app.switch_radSquare.Value
                case "Square"
                app.data=load(fullfile(app.root,'dbase',num2str(app.artery,'%02d'), 'po2',['pO2-run',num2str(app.depth_level,'%02d'),'.mat']));
            case "Radial"
                app.rad=true;
                app.data=load(fullfile(app.root,'dbase',num2str(app.artery,'%02d'), 'po2',['pO2-run',num2str(app.depth_level,'%02d'),'-r.mat']));
            end

            if app.imgtype~=0
                f_plotimg(app,app.image_Axis,app.data.pO2.references(app.imgtype).image);
            end
            if app.scat_type~="none"
                cla(app.image_Axis,'reset');
                f_plotimg(app,app.image_Axis,app.data.pO2.references(app.imgtype).image);
                f_plotscat(app,app.image_Axis,app.scat_type);
            end

            app.silder_brightness.Value=1;

            if app.switch_radSquare.Value=="Square"
                app.rad=false;
            else
                app.rad=true;
            end
        end

        % Display data changed function: tbl_metadata
        function tbl_metadataDisplayDataChanged(app, event)

        end

        % Value changed function: btn_clearFitPlot
        function btn_clearFitPlotValueChanged(app, event)
            app.btn_clearFitPlot.Value=0;
            app.decayplot_counter=0;
            g=get(app.ax_data,'children');
            t=findobj(g, 'Type', 'Hggroup'); delete(t);
            cla(app.ax_decay);
            cla(app.ax_residuals);
            app.btn_hidden2.Value=true;
            app.btn_exportPoint1.Enable='off';
            app.btn_exportPoint2.Enable='off';
        end

        % Value changed function: btn_exportMAtlab
        function btn_exportMAtlabValueChanged(app, event)
            [file, path]= uiputfile('*.mat');
            if file==0
                app.btn_exportMAtlab.Value=false;
                return
            end
            po2_data=app.data;
            save([path file],"po2_data")
            app.btn_exportMAtlab.Value=false;
        end

        % Value changed function: silder_brightness
        function silder_brightnessValueChanged(app, event)
            value = app.silder_brightness.Value;
            if app.imgtype~=0
                f_plotimg(app,app.image_Axis,value*app.data.pO2.references(app.imgtype).image);
            end
            if app.scat_type~="none"
                cla(app.image_Axis,'reset');
                f_plotimg(app,app.image_Axis,value*app.data.pO2.references(app.imgtype).image);
                f_plotscat(app,app.image_Axis,app.scat_type);
            end
        end

        % Callback function
        function ImageClicked(app, event)

        end

        % Value changed function: slider_depth
        function slider_depthValueChanged(app, event)
            value =int16(app.numdepths-app.slider_depth.Value);
            X=idivide(value,app.depth_inc,"round");
            hold= double(X*app.depth_inc);
            app.txt_depthvalue.Value=hold;
            f_calculatestack(app,value);
        end

        % Selection changed function: group_exportLineData
        function group_exportLineDataSelectionChanged(app, event)
            selectedButton = app.group_exportLineData.SelectedObject;
            if selectedButton==app.btn_exportLine1
                [file, path]= uiputfile('*.csv');
                if file==0
                    app.btn_exportLine1.Value=false;
                    return
                end
                writematrix(app.line1,[path file])
            end
            if selectedButton==app.btn_exportLine2
                [file, path]= uiputfile('*.csv');
                if file==0
                    app.btn_exportLine2.Value=false;
                    return
                end
                writematrix(app.line2,[path file])
            end

        end

        % Selection changed function: group_exportDecayData
        function group_exportDecayDataSelectionChanged(app, event)
            selectedButton = app.group_exportDecayData.SelectedObject;
            if selectedButton==app.btn_exportPoint1
                [file, path]= uiputfile('*.csv');
                if file==0
                    app.btn_exportPoint1.Value=false;
                    return
                end
                writematrix(app.point1,[path file])
            end
            if selectedButton==app.btn_exportPoint2
                [file, path]= uiputfile('*.csv');
                if file==0
                    app.btn_exportPoint2.Value=false;
                    return
                end
                writematrix(app.point2,[path file])
            end
        end

        % Value changed function: switch_logLin
        function switch_logLinValueChanged(app, event)
            switch app.switch_logLin.Value
                case "Log"
                    app.ax_decay.YScale="log";
                case "Linear"
                    app.ax_decay.YScale='Linear';
            end
        end

        % Button pushed function: btn_depth_up
        function btn_depth_upButtonPushed(app, event)
            if app.txt_depthvalue.Value+app.depth_inc>app.numdepths
                value=app.numdepths;
            else
                value=app.txt_depthvalue.Value+app.depth_inc;
            end
            app.slider_depth.Value=app.numdepths-value;
            f_calculatestack(app,value)
        end

        % Button pushed function: btn_depth_down
        function btn_depth_downButtonPushed(app, event)
            if app.txt_depthvalue.Value-app.depth_inc<0
                value=0;
            else
                value=app.txt_depthvalue.Value-app.depth_inc;
            end
            app.slider_depth.Value=app.numdepths-value;
            f_calculatestack(app,value)
        end

        % Value changed function: txt_depthvalue
        function txt_depthvalueValueChanged(app, event)

        end

        % Button pushed function: btn_findZPlane
        function btn_findZPlaneButtonPushed(app, event)
            if ~(app.numdepths-app.database.main(app.artery).data_depth(app.depth_level)>max(app.slider_depth.Limits)) && ~(app.numdepths-app.database.main(app.artery).data_depth(app.depth_level)<min(app.slider_depth.Limits))
                app.slider_depth.Value=app.numdepths-app.database.main(app.artery).data_depth(app.depth_level);
                app.txt_depthvalue.Value=app.database.main(app.artery).data_depth(app.depth_level);
            end
            f_calculatestack(app,app.txt_depthvalue.Value);
        end

        % Close request function: fig_Main
        function fig_MainCloseRequest(app, event)
            delete(app.DialogBoxApp)
            delete(app)
        end

        % Callback function
        function menu_datasetMenuSelected(app, event)
            delete(app.DialogBoxApp)
            app.DialogBoxApp=dlgBox(app);
        end

        % Menu selected function: menu_quit
        function menu_quitSelected(app, event)
            delete(app.DialogBoxApp)
            delete(app)
        end

        % Menu selected function: menu_dlgBox
        function menu_dlgBoxSelected(app, event)
            delete(app.DialogBoxApp)
            app.DialogBoxApp=dlgBox(app);
        end

        % Menu selected function: menu_Paper
        function menu_PaperMenuSelected(app, event)
            web('https://github.com/NIL-NeuroScience/tode/blob/958d9a7c92e2565f43872de027d2e9e244a35ae5/TODE_Manuscript_v1.pdf','-browser')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.fig_Main = uifigure('Visible', 'off');
            app.fig_Main.Name = 'Tissue Oxygen Depth Explorer';
            app.fig_Main.Icon = fullfile(pathToMLAPP, 'dbase', 'icon_no_text-01.png');
            app.fig_Main.CloseRequestFcn = createCallbackFcn(app, @fig_MainCloseRequest, true);
            app.menu_file = uimenu(app.fig_Main);
            app.menu_file.Text = 'File';
            app.menu_dlgBox = uimenu(app.menu_file);
            app.menu_dlgBox.MenuSelectedFcn = createCallbackFcn(app, @menu_dlgBoxSelected, true);
            app.menu_dlgBox.Text = 'Dataset selection';
            app.menu_quit = uimenu(app.menu_file);
            app.menu_quit.MenuSelectedFcn = createCallbackFcn(app, @menu_quitSelected, true);
            app.menu_quit.Text = 'Quit';
            app.menu_help = uimenu(app.fig_Main);
            app.menu_help.Text = 'Help';
            app.menu_Paper = uimenu(app.menu_help);
            app.menu_Paper.MenuSelectedFcn = createCallbackFcn(app, @menu_PaperMenuSelected, true);
            app.menu_Paper.Text = 'Link to paper';
            app.panel_dataDisplay = uipanel(app.fig_Main);
            app.panel_dataDisplay.Title = 'Data Display';
            app.ax_data = uiaxes(app.panel_dataDisplay);
            xlabel(app.ax_data, {''; ''})
            app.ax_data.XTick = [];
            app.ax_data.YTick = [];
            app.ReferenceImageButtonGroup = uibuttongroup(app.panel_dataDisplay);
            app.ReferenceImageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ReferenceImageButtonGroupSelectionChanged, true);
            app.ReferenceImageButtonGroup.Title = 'Reference Image';
            app.btn_Vessel = uitogglebutton(app.ReferenceImageButtonGroup);
            app.btn_Vessel.Text = 'FITC-Dextran';
            app.btn_RefSR101 = uitogglebutton(app.ReferenceImageButtonGroup);
            app.btn_RefSR101.Text = 'SR101';
            app.btn_RefSR101.Value = true;
            app.btn_RefOxyphor2P = uitogglebutton(app.ReferenceImageButtonGroup);
            app.btn_RefOxyphor2P.Text = 'Oxyphor 2p';
            app.btn_hiddenReference = uitogglebutton(app.ReferenceImageButtonGroup);
            app.btn_hiddenReference.Visible = 'off';
            app.btn_hiddenReference.Text = 'Button';
            app.group_data = uibuttongroup(app.panel_dataDisplay);
            app.group_data.SelectionChangedFcn = createCallbackFcn(app, @group_dataSelectionChanged, true);
            app.group_data.Title = 'Data';
            app.btn_plotCounts = uitogglebutton(app.group_data);
            app.btn_plotCounts.Text = 'Photon Count';
            app.btn_plotCounts.Value = true;
            app.btn_plotOxygen = uitogglebutton(app.group_data);
            app.btn_plotOxygen.Text = 'pO₂';
            app.btn_plotGrid = uitogglebutton(app.group_data);
            app.btn_plotGrid.Text = 'Grid';
            app.btn_hiddenData = uitogglebutton(app.group_data);
            app.btn_hiddenData.Visible = 'off';
            app.btn_hiddenData.Text = 'Button';
            app.group_depth = uibuttongroup(app.panel_dataDisplay);
            app.group_depth.SelectionChangedFcn = createCallbackFcn(app, @group_depthSelectionChanged, true);
            app.group_depth.Title = 'Depth in μm';
            app.btn_depth1 = uiradiobutton(app.group_depth);
            app.btn_depth1.Text = '0 ';
            app.btn_depth1.Value = true;
            app.btn_depth2 = uiradiobutton(app.group_depth);
            app.btn_depth2.Text = '100';
            app.btn_depth3 = uiradiobutton(app.group_depth);
            app.btn_depth3.Text = '200';
            app.btn_depth4 = uiradiobutton(app.group_depth);
            app.btn_depth4.Text = '290';
            app.btn_depth5 = uiradiobutton(app.group_depth);
            app.btn_depth5.Text = '345';
            app.btn_depth6 = uiradiobutton(app.group_depth);
            app.btn_depth6.Text = '385';
            app.btn_hiddenDepth = uiradiobutton(app.group_depth);
            app.btn_hiddenDepth.Visible = 'off';
            app.btn_hiddenDepth.Text = 'none';
            app.btn_findZPlane = uibutton(app.group_depth, 'push');
            app.btn_findZPlane.ButtonPushedFcn = createCallbackFcn(app, @btn_findZPlaneButtonPushed, true);
            app.btn_findZPlane.Text = '>> Z Plane';
            app.btn_clearDataPlot = uibutton(app.panel_dataDisplay, 'push');
            app.btn_clearDataPlot.ButtonPushedFcn = createCallbackFcn(app, @btn_clearDataPlotPushed, true);
            app.btn_clearDataPlot.Text = 'Clear Plot';
            app.switch_radSquare = uiswitch(app.panel_dataDisplay, 'slider');
            app.switch_radSquare.Items = {'Square', 'Radial'};
            app.switch_radSquare.ValueChangedFcn = createCallbackFcn(app, @switch_radSquareValueChanged, true);
            app.switch_radSquare.Enable = 'off';
            app.switch_radSquare.Value = 'Square';
            app.btn_exportMAtlab = uibutton(app.panel_dataDisplay, 'state');
            app.btn_exportMAtlab.ValueChangedFcn = createCallbackFcn(app, @btn_exportMAtlabValueChanged, true);
            app.btn_exportMAtlab.Text = 'Export Matlab Matrix';
            app.ax_scalebarData = uiimage(app.panel_dataDisplay);
            app.lbl_brightness = uilabel(app.panel_dataDisplay);
            app.lbl_brightness.Text = 'Brightness Adjustment';
            app.silder_brightness = uislider(app.panel_dataDisplay);
            app.silder_brightness.Limits = [0 2];
            app.silder_brightness.MajorTicks = [];
            app.silder_brightness.ValueChangedFcn = createCallbackFcn(app, @silder_brightnessValueChanged, true);
            app.silder_brightness.MinorTicks = [];
            app.panel_fitDisplay = uipanel(app.fig_Main);
            app.panel_fitDisplay.Title = 'pO₂ Fit Display ';
            app.ax_decay = uiaxes(app.panel_fitDisplay);
            xlabel(app.ax_decay, {'Time (μs)'; ''})
            ylabel(app.ax_decay, 'Photon Count')
            app.ax_decay.YScale = 'log';
            app.ax_residuals = uiaxes(app.panel_fitDisplay);
            xlabel(app.ax_residuals, 'Time (μs)')
            ylabel(app.ax_residuals, 'Residuals')
            app.ax_residuals.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.btn_selectPoint = uibutton(app.panel_fitDisplay, 'state');
            app.btn_selectPoint.ValueChangedFcn = createCallbackFcn(app, @btn_selectPointValueChanged, true);
            app.btn_selectPoint.Text = 'Select Point';
            app.btn_clearFitPlot = uibutton(app.panel_fitDisplay, 'state');
            app.btn_clearFitPlot.ValueChangedFcn = createCallbackFcn(app, @btn_clearFitPlotValueChanged, true);
            app.btn_clearFitPlot.Text = 'Clear';
            app.group_exportDecayData = uibuttongroup(app.panel_fitDisplay);
            app.group_exportDecayData.SelectionChangedFcn = createCallbackFcn(app, @group_exportDecayDataSelectionChanged, true);
            app.group_exportDecayData.Title = 'Export Decay Data';
            app.btn_exportPoint1 = uitogglebutton(app.group_exportDecayData);
            app.btn_exportPoint1.Text = 'Point 1';
            app.btn_exportPoint1.Value = true;
            app.btn_exportPoint2 = uitogglebutton(app.group_exportDecayData);
            app.btn_exportPoint2.Text = 'Point 2';
            app.btn_hidden2 = uitogglebutton(app.group_exportDecayData);
            app.btn_hidden2.Visible = 'off';
            app.btn_hidden2.Text = '';
            app.switch_logLin = uiswitch(app.panel_fitDisplay, 'slider');
            app.switch_logLin.Items = {'Log', 'Linear'};
            app.switch_logLin.ValueChangedFcn = createCallbackFcn(app, @switch_logLinValueChanged, true);
            app.switch_logLin.Value = 'Log';
            app.panel_interpolationProfilePlot = uipanel(app.fig_Main);
            app.panel_interpolationProfilePlot.Title = 'pO₂ Profile Plot';
            app.ax_linePlot = uiaxes(app.panel_interpolationProfilePlot);
            xlabel(app.ax_linePlot, 'Distance (μm)')
            ylabel(app.ax_linePlot, 'pO_2 (mmHg)')
            app.btn_drawLine = uibutton(app.panel_interpolationProfilePlot, 'state');
            app.btn_drawLine.ValueChangedFcn = createCallbackFcn(app, @btn_drawLineValueChanged, true);
            app.btn_drawLine.Text = 'Draw Line';
            app.btn_clearInterpolationPlot = uibutton(app.panel_interpolationProfilePlot, 'state');
            app.btn_clearInterpolationPlot.ValueChangedFcn = createCallbackFcn(app, @btn_clearInterpolationPlotValueChanged, true);
            app.btn_clearInterpolationPlot.Text = 'Clear Plot';
            app.group_exportLineData = uibuttongroup(app.panel_interpolationProfilePlot);
            app.group_exportLineData.SelectionChangedFcn = createCallbackFcn(app, @group_exportLineDataSelectionChanged, true);
            app.group_exportLineData.Title = 'Export Line Data';
            app.btn_exportLine1 = uitogglebutton(app.group_exportLineData);
            app.btn_exportLine1.Text = 'Line 1';
            app.btn_exportLine1.Value = true;
            app.btn_exportLine2 = uitogglebutton(app.group_exportLineData);
            app.btn_exportLine2.Text = 'Line 2';
            app.btn_hidden1 = uitogglebutton(app.group_exportLineData);
            app.btn_hidden1.Visible = 'off';
            app.btn_hidden1.Text = '';
            app.panel_ZStack = uipanel(app.fig_Main);
            app.panel_ZStack.Title = 'Vasculature Z Stack';
            app.ax_stack = uiaxes(app.panel_ZStack);
            app.ax_stack.XTick = [];
            app.ax_stack.YTick = [];
            app.slider_depth = uislider(app.panel_ZStack);
            app.slider_depth.Limits = [0 425];
            app.slider_depth.MajorTicks = [25 125 225 325 425];
            app.slider_depth.MajorTickLabels = {'0', '100', '200', '300', '400'};
            app.slider_depth.ValueChangedFcn = createCallbackFcn(app, @slider_depthValueChanged, true);
            app.slider_depth.MinorTicks = [0 25 50 75 100 125 150 175 200 225 250 275 300 325 350 375 400 425];
            app.switch_Zoom = uiswitch(app.panel_ZStack, 'slider');
            app.switch_Zoom.Items = {'Zoom out', 'Zoom in'};
            app.switch_Zoom.ValueChangedFcn = createCallbackFcn(app, @switch_ZoomValueChanged, true);
            app.switch_Zoom.Value = 'Zoom out';
            app.txt_depthvalue = uieditfield(app.panel_ZStack, 'numeric');
            app.txt_depthvalue.ValueChangedFcn = createCallbackFcn(app, @txt_depthvalueValueChanged, true);
            app.txt_depthvalue.Editable = 'off';
            app.btn_depth_down = uibutton(app.panel_ZStack, 'push');
            app.btn_depth_down.ButtonPushedFcn = createCallbackFcn(app, @btn_depth_downButtonPushed, true);
            app.btn_depth_down.Text = '-';
            app.btn_depth_up = uibutton(app.panel_ZStack, 'push');
            app.btn_depth_up.ButtonPushedFcn = createCallbackFcn(app, @btn_depth_upButtonPushed, true);
            app.btn_depth_up.Text = '+';
            app.ax_scalebarZStack = uiimage(app.panel_ZStack);
            app.ax_scalebarZStack.ImageSource = fullfile(pathToMLAPP, 'dbase', 'scalebar_stack_zoomout.png');
            app.lbl_depth = uilabel(app.panel_ZStack);
            app.lbl_depth.Text = 'Depth in μm';
            app.panel_metadata = uipanel(app.fig_Main);
            app.panel_metadata.Title = 'Metadata';
            app.tbl_metadata = uitable(app.panel_metadata);
            app.tbl_metadata.ColumnName = '';
            app.tbl_metadata.RowName = {};
            app.tbl_metadata.ColumnSortable = [false false];
            app.tbl_metadata.ColumnEditable = [false false];
            app.tbl_metadata.DisplayDataChangedFcn = createCallbackFcn(app, @tbl_metadataDisplayDataChanged, true);
            app.panel_exposure = uipanel(app.fig_Main);
            app.panel_exposure.Title = 'Exposure overview';
            app.ax_overviewImage = uiimage(app.panel_exposure);
            % Show the figure after all components are created
            app.fig_Main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TODE_v1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.fig_Main)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.fig_Main)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fig_Main)
        end
    end
end